classdef SingleCellGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        MainControlPanel              matlab.ui.container.Panel
        QuitButton                    matlab.ui.control.Button
        PauseButton                   matlab.ui.control.Button
        StartImagingButton            matlab.ui.control.Button
        WaitingforinstrumentstoconnectLabel  matlab.ui.control.Label
        StatusLabel                   matlab.ui.control.Label
        OutputDirectoryLabel          matlab.ui.control.Label
        BrowseButton                  matlab.ui.control.Button
        CameraControlPanel            matlab.ui.container.Panel
        GainDropDown                  matlab.ui.control.DropDown
        GainDropDownLabel             matlab.ui.control.Label
        BinningDropDown               matlab.ui.control.DropDown
        BinningDropDownLabel          matlab.ui.control.Label
        LiveViewButton                matlab.ui.control.StateButton
        ExposureTimemsEditField       matlab.ui.control.NumericEditField
        ExposureTimemsEditFieldLabel  matlab.ui.control.Label
        StageControlsPanel            matlab.ui.container.Panel
        CornersPanel                  matlab.ui.container.Panel
        GotoCornerButton              matlab.ui.control.Button
        SaveCornerButton              matlab.ui.control.Button
        ChipPositionsButtonGroup      matlab.ui.container.ButtonGroup
        bottomleftButton              matlab.ui.control.ToggleButton
        toprightButton                matlab.ui.control.ToggleButton
        topleftButton                 matlab.ui.control.ToggleButton
        PositionPanel                 matlab.ui.container.Panel
        ZEditField                    matlab.ui.control.NumericEditField
        ZEditField_3Label             matlab.ui.control.Label
        YEditField                    matlab.ui.control.NumericEditField
        YEditFieldLabel               matlab.ui.control.Label
        XEditField                    matlab.ui.control.NumericEditField
        XEditFieldLabel               matlab.ui.control.Label
        UpdateCurrentPositionButton   matlab.ui.control.Button
        Zdownpbutton                  matlab.ui.control.Button
        Zuppbutton                    matlab.ui.control.Button
        XYStepmSpinner                matlab.ui.control.Spinner
        XYStepmSpinnerLabel           matlab.ui.control.Label
        ZStepmSpinner                 matlab.ui.control.Spinner
        ZStepmSpinnerLabel            matlab.ui.control.Label
        ZStageMovementLabel           matlab.ui.control.Label
        XYPlaneStageMovementLabel     matlab.ui.control.Label
        XYuLeftbutton                 matlab.ui.control.Button
        XYRightbutton                 matlab.ui.control.Button
        XYDownbutton                  matlab.ui.control.Button
        XYUpbutton                    matlab.ui.control.Button
        LaserControlPanel             matlab.ui.container.Panel
        CheckGreen                    matlab.ui.control.CheckBox
        CheckCyan                     matlab.ui.control.CheckBox
        CheckBlue                     matlab.ui.control.CheckBox
        GREENEditField                matlab.ui.control.NumericEditField
        GREENEditFieldLabel           matlab.ui.control.Label
        CYANEditField                 matlab.ui.control.NumericEditField
        CYANEditFieldLabel            matlab.ui.control.Label
        BLUEEditField                 matlab.ui.control.NumericEditField
        BLUEEditFieldLabel            matlab.ui.control.Label
        GreenLabel                    matlab.ui.control.Label
        CyanLabel                     matlab.ui.control.Label
        BLUELabel                     matlab.ui.control.Label
        PowerLabel                    matlab.ui.control.Label
        OFFButtonGreen                matlab.ui.control.StateButton
        OFFButtonCyan                 matlab.ui.control.StateButton
        OFFButtonBlue                 matlab.ui.control.StateButton
        ONOFFLabel                    matlab.ui.control.Label
        ConnectionPanel               matlab.ui.container.Panel
        DisconnectButtonCamera        matlab.ui.control.Button
        ConnecttoCameraButton         matlab.ui.control.StateButton
        cameraconnectionLabel         matlab.ui.control.Label
        CameraStatusLabel             matlab.ui.control.Label
        DisconnectButtonLaser         matlab.ui.control.Button
        DisconnectButtonStage         matlab.ui.control.Button
        DisconnectButtonMicroscope    matlab.ui.control.Button
        ConnecttoLaserButton          matlab.ui.control.StateButton
        laserconnectionLabel          matlab.ui.control.Label
        LaserStatusLabel              matlab.ui.control.Label
        ConnecttoStageButton          matlab.ui.control.StateButton
        stageconnectionLabel          matlab.ui.control.Label
        StageStatusLabel              matlab.ui.control.Label
        MicroscopecoonectionLabel     matlab.ui.control.Label
        MicroscopeStatusLabel         matlab.ui.control.Label
        ConnecttoMicroscopeButton     matlab.ui.control.Button
    end

    
    properties (Access = public) %these are the global variables that can be accessed through the app structure
       % Description
       ti = -1; %microscope


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %run('init.m'); %this calls a function that appends all the
            %file paths o the matlab 
            movegui(app.UIFigure, "center");


        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: LiveViewButton
        function LiveViewButtonValueChanged(app, event)
            value = app.LiveViewButton.Value;
            
        end

        % Button pushed function: ConnecttoMicroscopeButton
        function ConnecttoMicroscopeButtonPushed(app, event)
            app.MicroscopecoonectionLabel.FontColor = "#EDB120";
            app.MicroscopecoonectionLabel.Text = "Connecting..." ;
            pause(4)
            %microscope = actxserver('Nikon.Ti.Microscope');
            %code to connect to the scope 
            %
            %
            %
            %app.ti = microscope; 
            app.MicroscopecoonectionLabel.FontColor = "#77AC30";
            app.MicroscopecoonectionLabel.Text = "Connected";


        end

        % Value changed function: ConnecttoStageButton
        function ConnecttoStageButtonValueChanged(app, event)
            %value = app.ConnecttoStageButton.Value;
            app.stageconnectionLabel.FontColor = "#EDB120";
            app.stageconnectionLabel.Text = "Connecting..." ;
            pause(4)
            %
            %
            %
            %
            app.stageconnectionLabel.FontColor = "#77AC30";
            app.stageconnectionLabel.Text = "Connected";


        end

        % Value changed function: ConnecttoLaserButton
        function ConnecttoLaserButtonValueChanged(app, event)
            %value = app.ConnecttoLaserButton.Value;
            app.laserconnectionLabel.FontColor = "#EDB120";
            app.laserconnectionLabel.Text = "Connecting..." ;
            pause(4)
            %
            %
            %
            %
            app.laserconnectionLabel.FontColor = "#77AC30";
            app.laserconnectionLabel.Text = "Connected";
            
        end

        % Value changed function: ConnecttoCameraButton
        function ConnecttoCameraButtonValueChanged(app, event)
            %value = app.ConnecttoCameraButton.Value;
            app.cameraconnectionLabel.FontColor = "#EDB120";
            app.cameraconnectionLabel.Text = "Connecting..." ;
            pause(4)
            %
            %
            %
            %
            app.cameraconnectionLabel.FontColor = "#77AC30";
            app.cameraconnectionLabel.Text = "Connected";
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConnectionPanel = uipanel(app.UIFigure);
            app.ConnectionPanel.Title = 'Connection';
            app.ConnecttoMicroscopeButton = uibutton(app.ConnectionPanel, 'push');
            app.ConnecttoMicroscopeButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoMicroscopeButtonPushed, true);
            app.ConnecttoMicroscopeButton.Text = 'Connect to Microscope';
            app.MicroscopeStatusLabel = uilabel(app.ConnectionPanel);
            app.MicroscopeStatusLabel.Text = 'Microscope Status: ';
            app.MicroscopecoonectionLabel = uilabel(app.ConnectionPanel);
            app.MicroscopecoonectionLabel.Text = 'Not Connect';
            app.StageStatusLabel = uilabel(app.ConnectionPanel);
            app.StageStatusLabel.Text = 'Stage Status: ';
            app.stageconnectionLabel = uilabel(app.ConnectionPanel);
            app.stageconnectionLabel.Text = 'Not Connect';
            app.ConnecttoStageButton = uibutton(app.ConnectionPanel, 'state');
            app.ConnecttoStageButton.ValueChangedFcn = createCallbackFcn(app, @ConnecttoStageButtonValueChanged, true);
            app.ConnecttoStageButton.Text = 'Connect to Stage';
            app.LaserStatusLabel = uilabel(app.ConnectionPanel);
            app.LaserStatusLabel.Text = 'Laser Status: ';
            app.laserconnectionLabel = uilabel(app.ConnectionPanel);
            app.laserconnectionLabel.Text = 'Not Connect';
            app.ConnecttoLaserButton = uibutton(app.ConnectionPanel, 'state');
            app.ConnecttoLaserButton.ValueChangedFcn = createCallbackFcn(app, @ConnecttoLaserButtonValueChanged, true);
            app.ConnecttoLaserButton.Text = 'Connect to Laser';
            app.DisconnectButtonMicroscope = uibutton(app.ConnectionPanel, 'push');
            app.DisconnectButtonMicroscope.Text = 'Disconnect';
            app.DisconnectButtonStage = uibutton(app.ConnectionPanel, 'push');
            app.DisconnectButtonStage.Text = 'Disconnect';
            app.DisconnectButtonLaser = uibutton(app.ConnectionPanel, 'push');
            app.DisconnectButtonLaser.Text = 'Disconnect';
            app.CameraStatusLabel = uilabel(app.ConnectionPanel);
            app.CameraStatusLabel.Text = 'Camera Status: ';
            app.cameraconnectionLabel = uilabel(app.ConnectionPanel);
            app.cameraconnectionLabel.Text = 'Not Connect';
            app.ConnecttoCameraButton = uibutton(app.ConnectionPanel, 'state');
            app.ConnecttoCameraButton.ValueChangedFcn = createCallbackFcn(app, @ConnecttoCameraButtonValueChanged, true);
            app.ConnecttoCameraButton.Text = 'Connect to Camera';
            app.DisconnectButtonCamera = uibutton(app.ConnectionPanel, 'push');
            app.DisconnectButtonCamera.Text = 'Disconnect';
            app.LaserControlPanel = uipanel(app.UIFigure);
            app.LaserControlPanel.Title = 'Laser Control ';
            app.ONOFFLabel = uilabel(app.LaserControlPanel);
            app.ONOFFLabel.Text = 'ON/OFF';
            app.OFFButtonBlue = uibutton(app.LaserControlPanel, 'state');
            app.OFFButtonBlue.Text = 'OFF';
            app.OFFButtonCyan = uibutton(app.LaserControlPanel, 'state');
            app.OFFButtonCyan.Text = 'OFF';
            app.OFFButtonGreen = uibutton(app.LaserControlPanel, 'state');
            app.OFFButtonGreen.Text = 'OFF';
            app.PowerLabel = uilabel(app.LaserControlPanel);
            app.PowerLabel.Text = 'Power (%)';
            app.BLUELabel = uilabel(app.LaserControlPanel);
            app.BLUELabel.Text = 'BLUE';
            app.CyanLabel = uilabel(app.LaserControlPanel);
            app.CyanLabel.Text = 'Cyan';
            app.GreenLabel = uilabel(app.LaserControlPanel);
            app.GreenLabel.Text = 'Green';
            app.BLUEEditFieldLabel = uilabel(app.LaserControlPanel);
            app.BLUEEditFieldLabel.Text = 'BLUE';
            app.BLUEEditField = uieditfield(app.LaserControlPanel, 'numeric');
            app.CYANEditFieldLabel = uilabel(app.LaserControlPanel);
            app.CYANEditFieldLabel.Text = 'CYAN';
            app.CYANEditField = uieditfield(app.LaserControlPanel, 'numeric');
            app.GREENEditFieldLabel = uilabel(app.LaserControlPanel);
            app.GREENEditFieldLabel.Text = 'GREEN';
            app.GREENEditField = uieditfield(app.LaserControlPanel, 'numeric');
            app.CheckBlue = uicheckbox(app.LaserControlPanel);
            app.CheckBlue.Text = '';
            app.CheckCyan = uicheckbox(app.LaserControlPanel);
            app.CheckCyan.Text = '';
            app.CheckGreen = uicheckbox(app.LaserControlPanel);
            app.CheckGreen.Text = '';
            app.StageControlsPanel = uipanel(app.UIFigure);
            app.StageControlsPanel.Title = 'Stage Controls';
            app.XYUpbutton = uibutton(app.StageControlsPanel, 'push');
            app.XYUpbutton.Text = '↑';
            app.XYDownbutton = uibutton(app.StageControlsPanel, 'push');
            app.XYDownbutton.Text = '↓';
            app.XYRightbutton = uibutton(app.StageControlsPanel, 'push');
            app.XYRightbutton.Text = '→';
            app.XYuLeftbutton = uibutton(app.StageControlsPanel, 'push');
            app.XYuLeftbutton.Text = '←';
            app.XYPlaneStageMovementLabel = uilabel(app.StageControlsPanel);
            app.XYPlaneStageMovementLabel.Text = 'XY Plane Stage Movement';
            app.ZStageMovementLabel = uilabel(app.StageControlsPanel);
            app.ZStageMovementLabel.Text = 'Z Stage Movement';
            app.ZStepmSpinnerLabel = uilabel(app.StageControlsPanel);
            app.ZStepmSpinnerLabel.Text = 'Z Step (µm)';
            app.ZStepmSpinner = uispinner(app.StageControlsPanel);
            app.ZStepmSpinner.Value = 10;
            app.XYStepmSpinnerLabel = uilabel(app.StageControlsPanel);
            app.XYStepmSpinnerLabel.Text = 'XY Step (µm)';
            app.XYStepmSpinner = uispinner(app.StageControlsPanel);
            app.XYStepmSpinner.Value = 10;
            app.Zuppbutton = uibutton(app.StageControlsPanel, 'push');
            app.Zuppbutton.Text = '↑';
            app.Zdownpbutton = uibutton(app.StageControlsPanel, 'push');
            app.Zdownpbutton.Text = '↓';
            app.PositionPanel = uipanel(app.StageControlsPanel);
            app.PositionPanel.Title = 'Position';
            app.UpdateCurrentPositionButton = uibutton(app.PositionPanel, 'push');
            app.UpdateCurrentPositionButton.Text = 'Update Current Position';
            app.XEditFieldLabel = uilabel(app.PositionPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.PositionPanel, 'numeric');
            app.YEditFieldLabel = uilabel(app.PositionPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.PositionPanel, 'numeric');
            app.ZEditField_3Label = uilabel(app.PositionPanel);
            app.ZEditField_3Label.Text = 'Z ';
            app.ZEditField = uieditfield(app.PositionPanel, 'numeric');
            app.CornersPanel = uipanel(app.StageControlsPanel);
            app.CornersPanel.Title = 'Corners';
            app.ChipPositionsButtonGroup = uibuttongroup(app.CornersPanel);
            app.ChipPositionsButtonGroup.Title = 'Chip Positions ';
            app.topleftButton = uitogglebutton(app.ChipPositionsButtonGroup);
            app.topleftButton.Text = 'topleft';
            app.topleftButton.Value = true;
            app.toprightButton = uitogglebutton(app.ChipPositionsButtonGroup);
            app.toprightButton.Text = 'topright';
            app.bottomleftButton = uitogglebutton(app.ChipPositionsButtonGroup);
            app.bottomleftButton.Text = 'bottomleft';
            app.SaveCornerButton = uibutton(app.CornersPanel, 'push');
            app.SaveCornerButton.Text = 'Save Corner';
            app.GotoCornerButton = uibutton(app.CornersPanel, 'push');
            app.GotoCornerButton.Text = 'Go to Corner';
            app.CameraControlPanel = uipanel(app.UIFigure);
            app.CameraControlPanel.Title = 'Camera Control';
            app.ExposureTimemsEditFieldLabel = uilabel(app.CameraControlPanel);
            app.ExposureTimemsEditFieldLabel.Text = 'Exposure Time (ms)';
            app.ExposureTimemsEditField = uieditfield(app.CameraControlPanel, 'numeric');
            app.ExposureTimemsEditField.Value = 1;
            app.LiveViewButton = uibutton(app.CameraControlPanel, 'state');
            app.LiveViewButton.ValueChangedFcn = createCallbackFcn(app, @LiveViewButtonValueChanged, true);
            app.LiveViewButton.Text = 'Live View ';
            app.BinningDropDownLabel = uilabel(app.CameraControlPanel);
            app.BinningDropDownLabel.Text = 'Binning';
            app.BinningDropDown = uidropdown(app.CameraControlPanel);
            app.BinningDropDown.Items = {'1x1', 'TBD'};
            app.BinningDropDown.Value = '1x1';
            app.GainDropDownLabel = uilabel(app.CameraControlPanel);
            app.GainDropDownLabel.Text = 'Gain';
            app.GainDropDown = uidropdown(app.CameraControlPanel);
            app.GainDropDown.Items = {'0', 'TBD'};
            app.GainDropDown.Value = '0';
            app.MainControlPanel = uipanel(app.UIFigure);
            app.MainControlPanel.Title = 'Main Control';
            app.BrowseButton = uibutton(app.MainControlPanel, 'push');
            app.BrowseButton.Text = 'Browse...';
            app.OutputDirectoryLabel = uilabel(app.MainControlPanel);
            app.OutputDirectoryLabel.Text = 'Output Directory';
            app.StatusLabel = uilabel(app.MainControlPanel);
            app.StatusLabel.Text = 'Status:';
            app.WaitingforinstrumentstoconnectLabel = uilabel(app.MainControlPanel);
            app.WaitingforinstrumentstoconnectLabel.Text = 'Waiting for instruments to connect ...';
            app.StartImagingButton = uibutton(app.MainControlPanel, 'push');
            app.StartImagingButton.Text = 'Start Imaging';
            app.PauseButton = uibutton(app.MainControlPanel, 'push');
            app.PauseButton.Text = 'Pause';
            app.QuitButton = uibutton(app.MainControlPanel, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SingleCellGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end